classdef final_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MaxFlightDistanceEditFieldLabel  matlab.ui.control.Label
        MaxFlightDistanceEditField  matlab.ui.control.NumericEditField
        VacationPlannerLabel        matlab.ui.control.Label
        DepartureCityDropDownLabel  matlab.ui.control.Label
        DepartureCityDropDown       matlab.ui.control.DropDown
        HottestDestinationsListBoxLabel  matlab.ui.control.Label
        HottestDestinationsListBox  matlab.ui.control.ListBox
        DatePickerLabel             matlab.ui.control.Label
        DatePicker                  matlab.ui.control.DatePicker
        StateDropDownLabel          matlab.ui.control.Label
        StateDropDown               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        airportCodes % Map of airports to their codes
        flightData % Map of codes to flights
        airportList % List of airports for dropdown
        states % List of states and abbreviations
    end
    
    methods (Access = private)
        
        function airports = filterState(app,state)
            abbr = app.findStateAbbr(state,1,size(app.states,1));
            if abbr ~= ""
                count = 0;
                for ii = 1:length(app.airportList)
                    city = char(app.airportList(ii));
                    if string(city(length(city)-1:length(city))) == string(abbr)
                        count = count + 1;
                    end
                end
                airports = strings(count,1);
                count = 0;
                for ii = 1:length(app.airportList)
                    city = char(app.airportList(ii));
                    if string(city(length(city)-1:length(city))) == string(abbr)
                        count = count + 1;
                        airports(count) = app.airportList(ii);
                    end
                end
                app.DepartureCityDropDown.Items = airports;
                app.DepartureCityDropDown.Enable = 'on';
                app.DepartureCityDropDownLabel.Enable = 'on';
            else
                app.DepartureCityDropDown.Items = strings(0);
                app.DepartureCityDropDown.Enable = 'off';
                app.DepartureCityDropDownLabel.Enable = 'off';
                app.HottestDestinationsListBox.Items = strings(0);
                app.HottestDestinationsListBox.Enable = 'off';
                app.HottestDestinationsListBoxLabel.Enable = 'off';
            end
        end
        
        function abbr = findStateAbbr(app,state,first,last)
            guess = floor((first+last)/2);
            if string(app.states{guess,1}) > string(state) && first ~= last
                abbr = app.findStateAbbr(state,first,guess);
            elseif string(app.states{guess,1}) < string(state) && first ~= last
                abbr = app.findStateAbbr(state,guess,last);
            else
                if string(app.states{guess,1}) == string(state) 
                    abbr = app.states{guess,2};
                else
                    abbr = "";
                end
            end
        end
        
        function statesProcessed = processStates(app,stateData)
            statesProcessed = stateData;
            for ii = 1:length(statesProcessed)
                names = split(statesProcessed{ii,1});
                if length(names) == 1
                    name = names{1};
                    name(2:end) = lower(name(2:end));
                elseif length(names) == 2
                    firstName = names{1};
                    firstName(2:end) = lower(firstName(2:end));
                    secondName = names{2};
                    secondName(2:end) = lower(secondName(2:end));
                    name = [firstName ' ' secondName];
                else
                    firstName = names{1};
                    firstName(2:end) = lower(firstName(2:end));
                    secondName = names{3};
                    secondName(2:end) = lower(secondName(2:end));
                    name = [firstName ' of ' secondName];
                end
                statesProcessed(ii,1) = {name};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = load("processedData.mat");
            app.airportCodes = data.airportCodes;
            app.flightData = data.theMap;
            app.states = app.processStates(data.states);
            airports = data.airportCodes.airportNameTable(~cellfun('isempty',data.airportCodes.airportNameTable));
            app.airportList = sort(airports(1:end/2));
            app.DepartureCityDropDown.Items = app.airportList;
            departCode = app.airportCodes.getCode(app.DepartureCityDropDown.Items(1));
            arrivals = app.flightData.get(departCode);
            airports = strings(size(arrivals,1),1);
            for ii = 1:size(arrivals,1)
                airports(ii) = app.airportCodes.getAirport(arrivals{ii,1});
            end
            app.HottestDestinationsListBox.Items = airports;
            time = datestr(now);
            time = time(1:find(time == ' ',1));
            app.DatePicker.Value = datetime(time);
            app.StateDropDown.Items = app.states(:,1);
            app.StateDropDown.Value = app.StateDropDown.Items(1);
            app.StateDropDownValueChanged()
        end

        % Value changed function: DepartureCityDropDown
        function DepartureCityDropDownValueChanged(app, event)
            departCity = app.DepartureCityDropDown.Value;
            departCode = app.airportCodes.getCode(departCity);
            if departCode ~= ""
                arrivals = app.flightData.get(departCode);
                airports = strings(size(arrivals,1),1);
                for ii = 1:size(arrivals,1)
                    airports(ii) = app.airportCodes.getAirport(arrivals{ii,1});
                    if ii == 6 
                        a = 1;
                    end
                end
                app.HottestDestinationsListBox.Items = unique(airports);
                app.HottestDestinationsListBox.Value = app.HottestDestinationsListBox.Items(1);
                app.HottestDestinationsListBox.Enable = 'on';
                app.HottestDestinationsListBoxLabel.Enable = 'on';
            else
                app.HottestDestinationsListBox.Items = strings(0);
                app.HottestDestinationsListBox.Enable = 'off';
                app.HottestDestinationsListBoxLabel.Enable = 'off';
            end
        end

        % Value changed function: StateDropDown
        function StateDropDownValueChanged(app, event)
            value = app.StateDropDown.Value;
            app.filterState(value);
            if ~isempty(app.DepartureCityDropDown.Items)
                app.DepartureCityDropDown.Value = app.DepartureCityDropDown.Items(1);
                app.DepartureCityDropDownValueChanged()
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MaxFlightDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.MaxFlightDistanceEditFieldLabel.Text = 'Max Flight Distance';
            app.MaxFlightDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxFlightDistanceEditField.Value = Inf;
            app.VacationPlannerLabel = uilabel(app.UIFigure);
            app.VacationPlannerLabel.Text = 'Vacation Planner';
            app.DepartureCityDropDownLabel = uilabel(app.UIFigure);
            app.DepartureCityDropDownLabel.Enable = 'off';
            app.DepartureCityDropDownLabel.Text = 'Departure City';
            app.DepartureCityDropDown = uidropdown(app.UIFigure);
            app.DepartureCityDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5'};
            app.DepartureCityDropDown.Editable = 'on';
            app.DepartureCityDropDown.ValueChangedFcn = createCallbackFcn(app, @DepartureCityDropDownValueChanged, true);
            app.DepartureCityDropDown.Enable = 'off';
            app.HottestDestinationsListBoxLabel = uilabel(app.UIFigure);
            app.HottestDestinationsListBoxLabel.Text = 'Hottest Destinations';
            app.HottestDestinationsListBox = uilistbox(app.UIFigure);
            app.HottestDestinationsListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5'};
            app.DatePickerLabel = uilabel(app.UIFigure);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.StateDropDownLabel = uilabel(app.UIFigure);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.UIFigure);
            app.StateDropDown.Editable = 'on';
            app.StateDropDown.ValueChangedFcn = createCallbackFcn(app, @StateDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end